classdef View < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        UseSelectedToolboxButton       matlab.ui.control.Button
        ToolboxToolboxDeployerLabel    matlab.ui.control.Label
        CopyCommandtoClipboardButton   matlab.ui.control.Button
        FilterToolboxesEditFieldLabel  matlab.ui.control.Label
        FilterToolboxesEditField       matlab.ui.control.EditField
        ToolboxesListBoxLabel          matlab.ui.control.Label
        ToolboxesListBox               matlab.ui.control.ListBox
        ChangeDirectoryDropDownLabel   matlab.ui.control.Label
        ChangeDirectoryDropDown        matlab.ui.control.DropDown
        ResetMatlabPathDropDownLabel   matlab.ui.control.Label
        ResetMatlabPathDropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        controller
        model
    end
    
    methods (Access = public)
        function setToolboxNames(app, toolboxNames)
            app.ToolboxesListBox.Items = toolboxNames;
        end
        
        function init(app, controller, model)
            app.controller = controller;
            app.model = model;
        end
        
        function setChangeDirectoryDropDown(app, value)
            app.ChangeDirectoryDropDown.Value = value;
        end
        
        function setResetDropDown(app, value)
            app.ResetMatlabPathDropDown.Value = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ResetMatlabPathDropDown.Items = {'Full' 'No Matlab Toolboxes' 'No self' 'Bare' 'As is'};
            app.ResetMatlabPathDropDown.ItemsData = {'full', 'no-matlab', 'no-self', 'bare', 'as-is'};
            
            app.ChangeDirectoryDropDown.Items = {'Yes' 'No' 'As specified'};
            app.ChangeDirectoryDropDown.ItemsData = {true, false, 'as-specified'};
        end

        % Value changing function: FilterToolboxesEditField
        function updateFilter(app, event)
            filteredToolboxNames = app.controller.filterToolboxes(event.Value);
            app.ToolboxesListBox.Items = filteredToolboxNames;
        end

        % Button pushed function: UseSelectedToolboxButton
        function useSelectedToolbox(app, event)
            toolboxNames = app.ToolboxesListBox.Value;
            app.controller.useAndClose(toolboxNames)
        end

        % Button pushed function: CopyCommandtoClipboardButton
        function copyToClipboardAndClose(app, event)
            toolboxNames = app.ToolboxesListBox.Value;
            app.controller.copyToClipboardAndClose(toolboxNames)
        end

        % Value changed function: ChangeDirectoryDropDown
        function ChangeDirectoryDropDownValueChanged(app, event)
            app.model.prefs.cdToFolder = app.ChangeDirectoryDropDown.Value;
        end

        % Value changed function: ResetMatlabPathDropDown
        function ResetMatlabPathDropDownValueChanged(app, event)
            app.model.prefs.reset = app.ResetMatlabPathDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UseSelectedToolboxButton = uibutton(app.GridLayout, 'push');
            app.UseSelectedToolboxButton.ButtonPushedFcn = createCallbackFcn(app, @useSelectedToolbox, true);
            app.UseSelectedToolboxButton.Text = 'Use Selected Toolbox';
            app.ToolboxToolboxDeployerLabel = uilabel(app.GridLayout);
            app.ToolboxToolboxDeployerLabel.Text = 'ToolboxToolbox Deployer';
            app.CopyCommandtoClipboardButton = uibutton(app.GridLayout, 'push');
            app.CopyCommandtoClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @copyToClipboardAndClose, true);
            app.CopyCommandtoClipboardButton.Text = 'Copy Command to Clipboard';
            app.FilterToolboxesEditFieldLabel = uilabel(app.GridLayout);
            app.FilterToolboxesEditFieldLabel.Text = 'Filter Toolboxes';
            app.FilterToolboxesEditField = uieditfield(app.GridLayout, 'text');
            app.FilterToolboxesEditField.ValueChangingFcn = createCallbackFcn(app, @updateFilter, true);
            app.ToolboxesListBoxLabel = uilabel(app.GridLayout);
            app.ToolboxesListBoxLabel.Text = 'Toolboxes';
            app.ToolboxesListBox = uilistbox(app.GridLayout);
            app.ToolboxesListBox.Multiselect = 'on';
            app.ToolboxesListBox.Value = {'Item 1'};
            app.ChangeDirectoryDropDownLabel = uilabel(app.GridLayout);
            app.ChangeDirectoryDropDownLabel.Text = 'Change Directory';
            app.ChangeDirectoryDropDown = uidropdown(app.GridLayout);
            app.ChangeDirectoryDropDown.Items = {'As specified', 'Yes', 'No', ''};
            app.ChangeDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeDirectoryDropDownValueChanged, true);
            app.ChangeDirectoryDropDown.Value = 'As specified';
            app.ResetMatlabPathDropDownLabel = uilabel(app.GridLayout);
            app.ResetMatlabPathDropDownLabel.Text = 'Reset Matlab Path';
            app.ResetMatlabPathDropDown = uidropdown(app.GridLayout);
            app.ResetMatlabPathDropDown.ValueChangedFcn = createCallbackFcn(app, @ResetMatlabPathDropDownValueChanged, true);
            app.ResetMatlabPathDropDown.Tooltip = {'see tbResetMatlabPath() for details'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = View

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end