classdef View < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ToolboxConfigurationFilePanel  matlab.ui.container.Panel
        ToolboxnameEditFieldLabel      matlab.ui.control.Label
        ToolboxnameEditField           matlab.ui.control.EditField
        SubfolderEditFieldLabel        matlab.ui.control.Label
        SubfolderEditField             matlab.ui.control.EditField
        PathplacementDropDownLabel     matlab.ui.control.Label
        PathplacementDropDown          matlab.ui.control.DropDown
        FilterToolboxesEditField       matlab.ui.control.EditField
        ToolboxesListBox               matlab.ui.control.ListBox
        DependenciesLabel              matlab.ui.control.Label
        GithubPanel                    matlab.ui.container.Panel
        URLDropDownLabel               matlab.ui.control.Label
        URLDropDown                    matlab.ui.control.DropDown
        VisibilityDropDownLabel        matlab.ui.control.Label
        VisibilityDropDown             matlab.ui.control.DropDown
        ShortDecriptionEditFieldLabel  matlab.ui.control.Label
        ShortDecriptionEditField       matlab.ui.control.EditField
        RepoNameEditFieldLabel         matlab.ui.control.Label
        RepoNameEditField              matlab.ui.control.EditField
        CreatenewtoolboxLabel          matlab.ui.control.Label
        CreateButton                   matlab.ui.control.Button
        PlannedActionsTextArea         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        model      % MVC model
        controller % MVC controller
    end
    
    methods (Access = public)
        function init(app, controller, model)
            app.controller = controller;
            app.model = model;
            
            % set default values
            app.ToolboxesListBox.Items = model.toolboxNames;
            app.ToolboxnameEditField.Value = model.getNewToolboxName;
            app.URLDropDown.Items = model.getGithubUrls;
            app.VisibilityDropDown.Value = model.getDefaultGithubVisibility;
            app.PlannedActionsTextArea.Value = model.getPlannedActions;
            app.RepoNameEditField.Value = model.getDefaultRepoName;
        end              

        function s = getSubfolder(app)
            s = app.SubfolderEditField.Value;
        end
        
        function s = getShortDescription(app)
            s = app.ShortDecriptionEditField.Value;
        end
        
        function deps = getDependencies(app)
            deps = app.ToolboxesListBox.Value;
        end
        
        function p = getPathPlacement(app)
            p = app.PathplacementDropDown.Value;
        end
        
        function v = getVisibility(app)
            v = app.VisibilityDropDown.Value;
        end
        
        function s = getGithubUrl(app)
            s = app.URLDropDown.Value;
        end
        
        function s = getGithubRepoName(app)
            s = app.RepoNameEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.controller.createToolbox;
        end

        % Value changing function: FilterToolboxesEditField
        function FilterToolboxesEditFieldValueChanging(app, event)
            filteredToolboxNames = app.controller.filterToolboxes(event.Value);
            app.ToolboxesListBox.Items = filteredToolboxNames;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ToolboxConfigurationFilePanel = uipanel(app.UIFigure);
            app.ToolboxConfigurationFilePanel.Title = 'Toolbox Configuration File';
            app.ToolboxnameEditFieldLabel = uilabel(app.ToolboxConfigurationFilePanel);
            app.ToolboxnameEditFieldLabel.Text = 'Toolbox name';
            app.ToolboxnameEditField = uieditfield(app.ToolboxConfigurationFilePanel, 'text');
            app.ToolboxnameEditField.Editable = 'off';
            app.ToolboxnameEditField.Enable = 'off';
            app.SubfolderEditFieldLabel = uilabel(app.ToolboxConfigurationFilePanel);
            app.SubfolderEditFieldLabel.Text = 'Subfolder';
            app.SubfolderEditField = uieditfield(app.ToolboxConfigurationFilePanel, 'text');
            app.PathplacementDropDownLabel = uilabel(app.ToolboxConfigurationFilePanel);
            app.PathplacementDropDownLabel.Text = 'Path placement';
            app.PathplacementDropDown = uidropdown(app.ToolboxConfigurationFilePanel);
            app.PathplacementDropDown.Items = {'none', 'prependrootonly', 'prepend', 'appendrootonly', 'append', 'mltbx'};
            app.PathplacementDropDown.Value = 'append';
            app.FilterToolboxesEditField = uieditfield(app.ToolboxConfigurationFilePanel, 'text');
            app.FilterToolboxesEditField.ValueChangingFcn = createCallbackFcn(app, @FilterToolboxesEditFieldValueChanging, true);
            app.ToolboxesListBox = uilistbox(app.ToolboxConfigurationFilePanel);
            app.ToolboxesListBox.Multiselect = 'on';
            app.ToolboxesListBox.Value = {};
            app.DependenciesLabel = uilabel(app.ToolboxConfigurationFilePanel);
            app.DependenciesLabel.Text = 'Dependencies';
            app.GithubPanel = uipanel(app.UIFigure);
            app.GithubPanel.Title = 'Github';
            app.URLDropDownLabel = uilabel(app.GithubPanel);
            app.URLDropDownLabel.Text = 'URL';
            app.URLDropDown = uidropdown(app.GithubPanel);
            app.VisibilityDropDownLabel = uilabel(app.GithubPanel);
            app.VisibilityDropDownLabel.Text = 'Visibility';
            app.VisibilityDropDown = uidropdown(app.GithubPanel);
            app.VisibilityDropDown.Items = {'public', 'private', 'internal'};
            app.VisibilityDropDown.Value = 'public';
            app.ShortDecriptionEditFieldLabel = uilabel(app.GithubPanel);
            app.ShortDecriptionEditFieldLabel.Text = 'Short Decription';
            app.ShortDecriptionEditField = uieditfield(app.GithubPanel, 'text');
            app.RepoNameEditFieldLabel = uilabel(app.GithubPanel);
            app.RepoNameEditFieldLabel.Text = 'Repo Name';
            app.RepoNameEditField = uieditfield(app.GithubPanel, 'text');
            app.CreatenewtoolboxLabel = uilabel(app.UIFigure);
            app.CreatenewtoolboxLabel.Text = 'Create new toolbox';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.PlannedActionsTextArea = uitextarea(app.UIFigure);
            app.PlannedActionsTextArea.Editable = 'off';
            app.PlannedActionsTextArea.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = View

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end